classdef ExponentaEditorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExponentaEditorUIFigure  matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        NotificationsTab         matlab.ui.container.Tab
        Panel                    matlab.ui.container.Panel
        Add                      matlab.ui.control.Button
        Delete                   matlab.ui.control.Button
        Load                     matlab.ui.control.Button
        Save                     matlab.ui.control.Button
        Show                     matlab.ui.control.Button
        Close                    matlab.ui.control.Button
        MaxwidthLabel            matlab.ui.control.Label
        MaxWidth                 matlab.ui.control.NumericEditField
        Up                       matlab.ui.control.Button
        Down                     matlab.ui.control.Button
        NotificationsTable       matlab.ui.control.Table
        MessageTextAreaLabel     matlab.ui.control.Label
        Message                  matlab.ui.control.TextArea
        Panel_2                  matlab.ui.container.Panel
        Checked                  matlab.ui.control.CheckBox
        ThemeDropDownLabel       matlab.ui.control.Label
        Theme                    matlab.ui.control.DropDown
        DueDateDatePickerLabel   matlab.ui.control.Label
        DueDate                  matlab.ui.control.DatePicker
        ActionsTable             matlab.ui.control.Table
        ActionsLabel             matlab.ui.control.Label
        Panel_3                  matlab.ui.container.Panel
        AddAction                matlab.ui.control.Button
        DeleteAction             matlab.ui.control.Button
        VideosTab                matlab.ui.container.Tab
        VideosTable              matlab.ui.control.Table
        Panel_4                  matlab.ui.container.Panel
        Add_2                    matlab.ui.control.Button
        Delete_2                 matlab.ui.control.Button
        Load_2                   matlab.ui.control.Button
        Save_2                   matlab.ui.control.Button
        Up_2                     matlab.ui.control.Button
        Down_2                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Storage
        Editor % ExponentaEditor
        Notifications % Notifications Reactive Table
        Notification
        Actions
        Videos
    end
    
    methods (Access = private)
        
        function data = ReadActions(app)
            data = app.Notifications.get('actions');
            if ~ischar(data{1})
                data = data{1};
            end
            if isempty(data)
                data = cell(0, 2);
            end
            data = cell2table(data, 'VariableNames', {'name' 'fcn'});
        end
        
        function WriteActions(app, data)
            if ~isempty(data)
                data = table2cell(data);
            else
                data = [];
            end
            app.Notifications.set('actions', {data});
        end
        
        function data = ReadVideos(app)
            data = app.Storage.get('videos');
        end
        
        function WriteVideos(app, data)
            app.Storage.set('videos', data);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadButtonPushed();
            app.Load_2ButtonPushed();
        end

        % Cell selection callback: NotificationsTable
        function NotificationsTableCellSelection(app, event)
            app.Notifications.select(event);
            app.Actions = UI.ReactiveTable([], @app.ReadActions, @app.WriteActions);
            app.Actions.bind(app.ActionsTable);
        end

        % Value changed function: Checked, DueDate, Message, Theme
        function MessageValueChanged(app, event)
            app.Notifications.updateVar(event);
        end

        % Display data changed function: NotificationsTable
        function NotificationsTableDisplayDataChanged(app, event)
            app.Notifications.update(event);
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            code = app.Editor.genCode();
            duedate = datetime + calmonths(1);
            app.Notifications.newRow('theme', 'info', 'code', code, 'duedate', duedate, 'actions', {[]});
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            app.Notifications.deleteRow();
        end

        % Callback function
        function SaveActionsButtonPushed(app, event)
            app.Actions.update();
        end

        % Cell selection callback: ActionsTable
        function ActionsTableCellSelection(app, event)
            app.Actions.select(event);
        end

        % Button pushed function: AddAction
        function AddActionButtonPushed(app, event)
            app.Actions.newRow('name', {'Подробности и регистрация'}, 'fcn', {'@(~,~)web("https://exponenta.ru")'});
        end

        % Cell edit callback: ActionsTable
        function ActionsTableCellEdit(app, event)
            app.Actions.update(event);
        end

        % Button pushed function: DeleteAction
        function DeleteActionButtonPushed(app, event)
            app.Actions.deleteRow();
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            app.Editor = ExponentaEditor;
            app.Notifications = UI.ReactiveTable(app.Editor, 'Data', 'Data');
            app.Notifications.select([1 1]);
            app.Notifications.bind(app.NotificationsTable);
            app.Notifications.bindVar('message', app.Message);
            app.Notifications.bindVar('theme', app.Theme);
            app.Notifications.bindVar('checked', app.Checked);
            app.Notifications.bindVar('duedate', app.DueDate);
            app.NotificationsTableCellSelection([1 1]);
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            if ~isempty(app.Editor)
                app.Editor.save();
            end
        end

        % Button pushed function: Show
        function ShowPushed(app, event)
            app.CloseButtonPushed();
            data = app.Notifications.getRow();
            app.Notification = app.Editor.showNotification(app.ExponentaEditorUIFigure, data);
            app.Notification.Root.Position(3) = app.MaxWidth.Value;
        end

        % Button pushed function: Close
        function CloseButtonPushed(app, event)
            delete(app.Notification);
        end

        % Button pushed function: Load_2
        function Load_2ButtonPushed(app, event)
            app.Storage = ExponentaStorage('fdir', 'data', 'local', 1, 'auto', 0);
            app.Storage.load();
            app.Videos = UI.ReactiveTable([], @app.ReadVideos, @app.WriteVideos);
            app.Videos.bind(app.VideosTable);
        end

        % Button pushed function: Save_2
        function Save_2ButtonPushed(app, event)
            app.Storage.save();
        end

        % Button pushed function: Add_2
        function Add_2ButtonPushed(app, event)
            app.Videos.newRow('name', "", 'url', "");
        end

        % Button pushed function: Delete_2
        function Delete_2ButtonPushed(app, event)
            app.Videos.deleteRow();
        end

        % Cell selection callback: VideosTable
        function VideosTableCellSelection(app, event)
            app.Videos.select(event);
        end

        % Display data changed function: VideosTable
        function VideosTableDisplayDataChanged(app, event)
            app.Videos.update(event);
        end

        % Button pushed function: Up
        function UpPushed(app, event)
            app.Notifications.moveRowUp();
        end

        % Button pushed function: Down
        function DownButtonPushed(app, event)
            app.Notifications.moveRowDown();
        end

        % Button pushed function: Up_2
        function Up_2ButtonPushed(app, event)
            app.Videos.moveRowUp();
        end

        % Button pushed function: Down_2
        function Down_2ButtonPushed(app, event)
            app.Videos.moveRowDown();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExponentaEditorUIFigure = uifigure('Visible', 'off');
            app.ExponentaEditorUIFigure.Name = 'Exponenta Editor';
            app.TabGroup = uitabgroup(app.ExponentaEditorUIFigure);
            app.NotificationsTab = uitab(app.TabGroup);
            app.NotificationsTab.Title = 'Notifications';
            app.Panel = uipanel(app.NotificationsTab);
            app.Add = uibutton(app.Panel, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = 'Add';
            app.Delete = uibutton(app.Panel, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Delete';
            app.Load = uibutton(app.Panel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.Save = uibutton(app.Panel, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save';
            app.Show = uibutton(app.Panel, 'push');
            app.Show.ButtonPushedFcn = createCallbackFcn(app, @ShowPushed, true);
            app.Show.Text = 'Show';
            app.Close = uibutton(app.Panel, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.Close.Text = 'Close';
            app.MaxwidthLabel = uilabel(app.Panel);
            app.MaxwidthLabel.Text = 'Max width';
            app.MaxWidth = uieditfield(app.Panel, 'numeric');
            app.MaxWidth.Value = 375;
            app.Up = uibutton(app.Panel, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpPushed, true);
            app.Up.Text = '↑';
            app.Down = uibutton(app.Panel, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.Down.Text = '↓';
            app.NotificationsTable = uitable(app.NotificationsTab);
            app.NotificationsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; ''};
            app.NotificationsTable.RowName = {};
            app.NotificationsTable.ColumnEditable = [false true true true true];
            app.NotificationsTable.CellSelectionCallback = createCallbackFcn(app, @NotificationsTableCellSelection, true);
            app.NotificationsTable.DisplayDataChangedFcn = createCallbackFcn(app, @NotificationsTableDisplayDataChanged, true);
            app.MessageTextAreaLabel = uilabel(app.NotificationsTab);
            app.MessageTextAreaLabel.Text = 'Message';
            app.Message = uitextarea(app.NotificationsTab);
            app.Message.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.Panel_2 = uipanel(app.NotificationsTab);
            app.Checked = uicheckbox(app.Panel_2);
            app.Checked.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.Checked.Text = 'Checked';
            app.ThemeDropDownLabel = uilabel(app.Panel_2);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.Theme = uidropdown(app.Panel_2);
            app.Theme.Items = {'dark', 'light', 'success', 'info', 'warning', 'danger'};
            app.Theme.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.Theme.Value = 'dark';
            app.DueDateDatePickerLabel = uilabel(app.Panel_2);
            app.DueDateDatePickerLabel.Text = 'Due Date';
            app.DueDate = uidatepicker(app.Panel_2);
            app.DueDate.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.ActionsTable = uitable(app.NotificationsTab);
            app.ActionsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ActionsTable.RowName = {};
            app.ActionsTable.ColumnEditable = [true true false false];
            app.ActionsTable.CellEditCallback = createCallbackFcn(app, @ActionsTableCellEdit, true);
            app.ActionsTable.CellSelectionCallback = createCallbackFcn(app, @ActionsTableCellSelection, true);
            app.ActionsLabel = uilabel(app.NotificationsTab);
            app.ActionsLabel.Text = 'Actions';
            app.Panel_3 = uipanel(app.NotificationsTab);
            app.AddAction = uibutton(app.Panel_3, 'push');
            app.AddAction.ButtonPushedFcn = createCallbackFcn(app, @AddActionButtonPushed, true);
            app.AddAction.Text = 'Add';
            app.DeleteAction = uibutton(app.Panel_3, 'push');
            app.DeleteAction.ButtonPushedFcn = createCallbackFcn(app, @DeleteActionButtonPushed, true);
            app.DeleteAction.Text = 'Delete';
            app.VideosTab = uitab(app.TabGroup);
            app.VideosTab.Title = 'Videos';
            app.VideosTable = uitable(app.VideosTab);
            app.VideosTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.VideosTable.RowName = {};
            app.VideosTable.ColumnEditable = [true true false false];
            app.VideosTable.CellSelectionCallback = createCallbackFcn(app, @VideosTableCellSelection, true);
            app.VideosTable.DisplayDataChangedFcn = createCallbackFcn(app, @VideosTableDisplayDataChanged, true);
            app.Panel_4 = uipanel(app.VideosTab);
            app.Add_2 = uibutton(app.Panel_4, 'push');
            app.Add_2.ButtonPushedFcn = createCallbackFcn(app, @Add_2ButtonPushed, true);
            app.Add_2.Text = 'Add';
            app.Delete_2 = uibutton(app.Panel_4, 'push');
            app.Delete_2.ButtonPushedFcn = createCallbackFcn(app, @Delete_2ButtonPushed, true);
            app.Delete_2.Text = 'Delete';
            app.Load_2 = uibutton(app.Panel_4, 'push');
            app.Load_2.ButtonPushedFcn = createCallbackFcn(app, @Load_2ButtonPushed, true);
            app.Load_2.Text = 'Load';
            app.Save_2 = uibutton(app.Panel_4, 'push');
            app.Save_2.ButtonPushedFcn = createCallbackFcn(app, @Save_2ButtonPushed, true);
            app.Save_2.Text = 'Save';
            app.Up_2 = uibutton(app.Panel_4, 'push');
            app.Up_2.ButtonPushedFcn = createCallbackFcn(app, @Up_2ButtonPushed, true);
            app.Up_2.Text = '↑';
            app.Down_2 = uibutton(app.Panel_4, 'push');
            app.Down_2.ButtonPushedFcn = createCallbackFcn(app, @Down_2ButtonPushed, true);
            app.Down_2.Text = '↓';
            % Show the figure after all components are created
            app.ExponentaEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExponentaEditorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExponentaEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExponentaEditorUIFigure)
        end
    end
end